classdef magnetalignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DisconnectMotorsButton  matlab.ui.control.Button
        InfoTextArea            matlab.ui.control.TextArea
        InfoTextAreaLabel       matlab.ui.control.Label
        ZAxisPanel              matlab.ui.container.Panel
        EnableCheckBox_3        matlab.ui.control.CheckBox
        EditField_3             matlab.ui.control.NumericEditField
        PtsEditField_3          matlab.ui.control.NumericEditField
        PtsEditField_3Label     matlab.ui.control.Label
        MaxEditField_3          matlab.ui.control.NumericEditField
        MaxEditField_3Label     matlab.ui.control.Label
        MinEditField_3          matlab.ui.control.NumericEditField
        MinEditField_3Label     matlab.ui.control.Label
        YAxisPanel              matlab.ui.container.Panel
        EnableCheckBox_2        matlab.ui.control.CheckBox
        EditField_2             matlab.ui.control.NumericEditField
        PtsEditField_2          matlab.ui.control.NumericEditField
        PtsEditField_2Label     matlab.ui.control.Label
        MaxEditField_2          matlab.ui.control.NumericEditField
        MaxEditField_2Label     matlab.ui.control.Label
        MinEditField_2          matlab.ui.control.NumericEditField
        MinEditField_2Label     matlab.ui.control.Label
        XAxisPanel              matlab.ui.container.Panel
        EnableCheckBox          matlab.ui.control.CheckBox
        EditField               matlab.ui.control.NumericEditField
        PtsEditField            matlab.ui.control.NumericEditField
        PtsEditFieldLabel       matlab.ui.control.Label
        MaxEditField            matlab.ui.control.NumericEditField
        MaxEditFieldLabel       matlab.ui.control.Label
        MinEditField            matlab.ui.control.NumericEditField
        MinEditFieldLabel       matlab.ui.control.Label
        SettocursorButton       matlab.ui.control.Button
        STOPSCANButton          matlab.ui.control.Button
        STARTSCANButton         matlab.ui.control.Button
        ConnectMotorsButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
                % --- 逻辑属性 ---
        Acq                 % ImageAcquisitionThorlabs 对象
        NI                  % CounterAcquisition 对象
        ScanAbort = false;  % 停止标志位
    end
    
    methods (Access = private)
        
        % --- 辅助功能 ---
        function updatePositions(app)
            % 更新界面上的实时位置（蓝色背景框）
            try
                app.EditField.Value = System.Decimal.ToDouble(app.Acq.Motors.X.Position);
                app.EditField_2.Value = System.Decimal.ToDouble(app.Acq.Motors.Y.Position);
                app.EditField_3.Value = System.Decimal.ToDouble(app.Acq.Motors.Z.Position);
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, niObj)
            % 1. 将外部传进来的硬件对象赋值给本 App 的属性
            if nargin > 0
                % app.Acq = acqObj;
                app.NI = niObj;

                % 更新界面状态提示
                app.InfoTextArea.Value = '硬件对象已从主程序成功挂载。';

                % (可选) 自动更新一下界面上的电机位置
                % app.updatePositions();
            else
                app.InfoTextArea.Value = '警告：未检测到输入对象，请检查启动方式。';
            end
        end

        % Button pushed function: ConnectMotorsButton
        function ConnectMotorsButtonPushed(app, event)
            % --- 逻辑 1：连接电机 (参考 thorlabmotortest.m) ---
            app.InfoTextArea.Value = '正在建立连接...';
            try
 
                % 加载 Kinesis 库
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.DeviceManagerCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.KCube.DCServoCLI.dll');

                import Thorlabs.MotionControl.DeviceManagerCLI.*
                import Thorlabs.MotionControl.GenericMotorCLI.*
                import Thorlabs.MotionControl.KCube.DCServoCLI.*

                DeviceManagerCLI.BuildDeviceList();
                serialNumbers = cell(ToArray(DeviceManagerCLI.GetDeviceList()));
                
                if length(serialNumbers) < 3
                    uialert(app.UIFigure, '检测到的序列号不足3个！', '硬件错误');
                    return;
                end

                % 这里假设顺序与 thorlabmotortest 一致：X, Z, Y
                aliases = {'X', 'Z', 'Y'};

                for i = 1:3
                    sn = serialNumbers{i};
                    dev = KCubeDCServo.CreateKCubeDCServo(sn);
                    dev.Connect(sn);
                    dev.WaitForSettingsInitialized(5000);
                    cfg = dev.LoadMotorConfiguration(sn);
                    cfg.DeviceSettingsName = 'MTS25-Z8'; 
                    cfg.UpdateCurrentConfiguration();
                    dev.SetSettings(dev.MotorDeviceSettings, true, false);
                    dev.StartPolling(250);
                    app.Acq.Motors.(aliases{i}) = dev;
                end
                
                app.InfoTextArea.Value = '电机连接成功！';
                app.updatePositions();
            catch ME
                app.InfoTextArea.Value = ['连接失败: ' ME.message];
            end
        end

        % Button pushed function: DisconnectMotorsButton
        function DisconnectMotorsButtonPushed(app, event)
            app.InfoTextArea.Value = '正在断开电机连接...';

            % 检查 Acq 对象及 Motors 属性是否存在
            if isempty(app.Acq) || isempty(app.Acq.Motors)
                app.InfoTextArea.Value = '未检测到已连接的电机。';
                return;
            end

            % 定义需要断开的轴别名
            aliases = {'X', 'Y', 'Z'};

            try
                for i = 1:length(aliases)
                    ax = aliases{i};
                    % 检查该轴是否已初始化并连接
                    if ~isempty(app.Acq.Motors.(ax)) && app.Acq.Motors.(ax).IsConnected
                        % 1. 停止轮询 (参考 thorlabmotortest.m)
                        app.Acq.Motors.(ax).StopPolling();

                        % 2. 断开连接
                        app.Acq.Motors.(ax).Disconnect();

                        % 3. 清空句柄防止误操作
                        app.Acq.Motors.(ax) = [];
                    end
                end
                app.InfoTextArea.Value = '所有设备已安全断开。';
            catch ME
                app.InfoTextArea.Value = ['断开连接时出错: ' ME.message];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % 调用我们之前写好的断开连接函数
                app.DisconnectMotorsButtonPushed();
            catch
                % 防止断开过程中报错导致窗口关不掉
            end

            % 2. 正常销毁 UI 窗口

            delete(app)

        end

        % Button pushed function: STARTSCANButton
        function STARTSCANButtonPushed(app, event)
             % --- 逻辑 2：2D Zig-Zag 扫描 (参考 ImageAcquisitionThorlabs.m) ---
            app.ScanAbort = false;
            app.InfoTextArea.Value = '开始扫描...';
            
            % 更新扫描参数
            app.Acq.CurrentScan.MinValues = [app.MinEditField.Value, app.MinEditField_2.Value, app.MinEditField_3.Value];
            app.Acq.CurrentScan.MaxValues = [app.MaxEditField.Value, app.MaxEditField_2.Value, app.MaxEditField_3.Value];
            app.Acq.CurrentScan.NumPoints = [app.PtsEditField.Value, app.PtsEditField_2.Value, app.PtsEditField_3.Value];
            app.Acq.CurrentScan.bEnable = [app.EnableCheckBox.Value, app.EnableCheckBox_2.Value, app.EnableCheckBox_3.Value];
            
            % 初始化向量 (调用基类方法)
            app.Acq.InitVarForScan();
            
            % 获取启用的两个轴 (Fast & Slow)
            enabledAxes = find(app.Acq.CurrentScan.bEnable == 1);
            if length(enabledAxes) ~= 2
                uialert(app.UIFigure, '请确保正好勾选了两个 Enable 轴进行2D扫描', '设置错误');
                return;
            end
            
            fIdx = enabledAxes(1); sIdx = enabledAxes(2);
            fMotor = app.Acq.getMotorByIndex(fIdx);
            sMotor = app.Acq.getMotorByIndex(sIdx);
            fVec = app.Acq.getVecByIndex(fIdx);
            sVec = app.Acq.getVecByIndex(sIdx);
            
            mapData = zeros(length(fVec), length(sVec));

            % 2D 循环逻辑
            for j = 1:length(sVec)
                if app.AbortScan, break; end
                
                % 移动慢速轴
                sMotor.MoveTo(System.Decimal(sVec(j)), 60000);
                
                % Zig-Zag 方向判定
                if mod(j,2) == 1, iRange = 1:length(fVec); else, iRange = length(fVec):-1:1; end
                
                for i = iRange
                    if app.AbortScan, break; end
                    
                    % 移动快速轴
                    fMotor.MoveTo(System.Decimal(fVec(i)), 60000);
                    
                    % 采集计数
                    app.NI.GetCountsPerSecond();
                    mapData(i, j) = app.NI.CountsPerSecond;
                    
                    % 实时刷新图像
                    imagesc(app.UIAxes, fVec, sVec, mapData');
                    axis(app.UIAxes, 'tight'); colorbar(app.UIAxes);
                    drawnow limitrate;
                end
            end
            app.InfoTextArea.Value = '扫描完成。';
            app.updatePositions();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMotorsButtonPushed, true);
            app.ConnectMotorsButton.Text = 'Connect Motors';
            app.STARTSCANButton = uibutton(app.UIFigure, 'push');
            app.STARTSCANButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSCANButtonPushed, true);
            app.STARTSCANButton.Text = 'START SCAN';
            app.STOPSCANButton = uibutton(app.UIFigure, 'push');
            app.STOPSCANButton.Text = 'STOP SCAN';
            app.SettocursorButton = uibutton(app.UIFigure, 'push');
            app.SettocursorButton.Text = 'Set to cursor';
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X Axis';
            app.MinEditFieldLabel = uilabel(app.XAxisPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MinEditField.Limits = [8 10];
            app.MinEditField.Value = 9;
            app.MaxEditFieldLabel = uilabel(app.XAxisPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MaxEditField.Limits = [10 12];
            app.MaxEditField.Value = 11;
            app.PtsEditFieldLabel = uilabel(app.XAxisPanel);
            app.PtsEditFieldLabel.Text = 'Pts';
            app.PtsEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PtsEditField.Value = 10;
            app.EditField = uieditfield(app.XAxisPanel, 'numeric');
            app.EditField.Value = 10;
            app.EnableCheckBox = uicheckbox(app.XAxisPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.YAxisPanel = uipanel(app.UIFigure);
            app.YAxisPanel.Title = 'Y Axis';
            app.MinEditField_2Label = uilabel(app.YAxisPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MinEditField_2.Limits = [8 10];
            app.MinEditField_2.Value = 9;
            app.MaxEditField_2Label = uilabel(app.YAxisPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MaxEditField_2.Limits = [10 12];
            app.MaxEditField_2.Value = 11;
            app.PtsEditField_2Label = uilabel(app.YAxisPanel);
            app.PtsEditField_2Label.Text = 'Pts';
            app.PtsEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.PtsEditField_2.Value = 10;
            app.EditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.EditField_2.Value = 10;
            app.EnableCheckBox_2 = uicheckbox(app.YAxisPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z Axis';
            app.MinEditField_3Label = uilabel(app.ZAxisPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MinEditField_3.Limits = [8 10];
            app.MinEditField_3.Value = 9;
            app.MaxEditField_3Label = uilabel(app.ZAxisPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MaxEditField_3.Limits = [0 20];
            app.MaxEditField_3.Value = 20;
            app.PtsEditField_3Label = uilabel(app.ZAxisPanel);
            app.PtsEditField_3Label.Text = 'Pts';
            app.PtsEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.PtsEditField_3.Value = 10;
            app.EditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.EnableCheckBox_3 = uicheckbox(app.ZAxisPanel);
            app.EnableCheckBox_3.Text = 'Enable';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.DisconnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectMotorsButtonPushed, true);
            app.DisconnectMotorsButton.Text = 'Disconnect Motors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magnetalignment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end