classdef magnetalignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GaussianfitButton       matlab.ui.control.Button
        ROIButton               matlab.ui.control.Button
        CursorButton            matlab.ui.control.Button
        DisconnectMotorsButton  matlab.ui.control.Button
        InfoTextArea            matlab.ui.control.TextArea
        InfoTextAreaLabel       matlab.ui.control.Label
        ZAxisPanel              matlab.ui.container.Panel
        SpeedEditField_3        matlab.ui.control.NumericEditField
        SpeedEditField_3Label   matlab.ui.control.Label
        EnableCheckBox_3        matlab.ui.control.CheckBox
        EditField_3             matlab.ui.control.NumericEditField
        PtsEditField_3          matlab.ui.control.NumericEditField
        PtsEditField_3Label     matlab.ui.control.Label
        MaxEditField_3          matlab.ui.control.NumericEditField
        MaxEditField_3Label     matlab.ui.control.Label
        MinEditField_3          matlab.ui.control.NumericEditField
        MinEditField_3Label     matlab.ui.control.Label
        YAxisPanel              matlab.ui.container.Panel
        SpeedEditField_2        matlab.ui.control.NumericEditField
        SpeedEditField_2Label   matlab.ui.control.Label
        EnableCheckBox_2        matlab.ui.control.CheckBox
        EditField_2             matlab.ui.control.NumericEditField
        PtsEditField_2          matlab.ui.control.NumericEditField
        PtsEditField_2Label     matlab.ui.control.Label
        MaxEditField_2          matlab.ui.control.NumericEditField
        MaxEditField_2Label     matlab.ui.control.Label
        MinEditField_2          matlab.ui.control.NumericEditField
        MinEditField_2Label     matlab.ui.control.Label
        XAxisPanel              matlab.ui.container.Panel
        SpeedEditField          matlab.ui.control.NumericEditField
        SpeedEditFieldLabel     matlab.ui.control.Label
        EnableCheckBox          matlab.ui.control.CheckBox
        EditField               matlab.ui.control.NumericEditField
        PtsEditField            matlab.ui.control.NumericEditField
        PtsEditFieldLabel       matlab.ui.control.Label
        MaxEditField            matlab.ui.control.NumericEditField
        MaxEditFieldLabel       matlab.ui.control.Label
        MinEditField            matlab.ui.control.NumericEditField
        MinEditFieldLabel       matlab.ui.control.Label
        SettocursorButton       matlab.ui.control.Button
        STOPSCANButton          matlab.ui.control.Button
        STARTSCANButton         matlab.ui.control.Button
        ConnectMotorsButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
                % --- 逻辑属性 ---
        Acq                 % ImageAcquisitionThorlabs 对象
        NI                  % CounterAcquisition 对象
        AbortScan = false;  % 停止标志位
        CursorPickActive = false
        CursorMarker
        GaussianCenterMarker
        GaussianRoi
    end
    
    methods (Access = private)
        
        % --- 辅助功能 ---
        function updatePositions(app)
            % 更新界面上的实时位置（蓝色背景框）
            try
                app.EditField.Value = System.Decimal.ToDouble(app.Acq.Motors.X.Position);
                app.EditField_2.Value = System.Decimal.ToDouble(app.Acq.Motors.Y.Position);
                app.EditField_3.Value = System.Decimal.ToDouble(app.Acq.Motors.Z.Position);
            catch
            end
        end


        function applyScanAxisLimits(app)
            xLimits = sort([app.MinEditField.Value, app.MaxEditField.Value]);
            yLimits = sort([app.MinEditField_2.Value, app.MaxEditField_2.Value]);
            if xLimits(1) == xLimits(2) || yLimits(1) == yLimits(2)
                return;
            end
            app.UIAxes.XLim = xLimits;
            app.UIAxes.YLim = yLimits;
        end

        function imageObject = getCurrentImage(app)
            imageObjects = findobj(app.UIAxes,'Type','Image');
            if isempty(imageObjects)
                imageObject = [];
            else
                imageObject = imageObjects(1);
            end
        end

        function [xGrid,yGrid] = getImageGrid(app,imageObject,imageSize)
            nY = imageSize(1);
            nX = imageSize(2);
            xData = imageObject.XData;
            yData = imageObject.YData;
            if numel(xData) == nX
                xVec = double(xData(:))';
            elseif numel(xData) == 2
                xVec = linspace(double(xData(1)),double(xData(2)),nX);
            else
                xVec = linspace(app.UIAxes.XLim(1),app.UIAxes.XLim(2),nX);
            end
            if numel(yData) == nY
                yVec = double(yData(:));
            elseif numel(yData) == 2
                yVec = linspace(double(yData(1)),double(yData(2)),nY)';
            else
                yVec = linspace(app.UIAxes.YLim(1),app.UIAxes.YLim(2),nY)';
            end
            [xGrid,yGrid] = meshgrid(xVec,yVec);
        end

        function smoothed = smooth2D(app,data,windowSize)
            halfWindow = floor(windowSize/2);
            smoothed = data;
            for row = 1:size(data,1)
                for col = 1:size(data,2)
                    r1 = max(1,row-halfWindow);
                    r2 = min(size(data,1),row+halfWindow);
                    c1 = max(1,col-halfWindow);
                    c2 = min(size(data,2),col+halfWindow);
                    block = data(r1:r2,c1:c2);
                    block = block(isfinite(block) & block > 0);
                    if ~isempty(block)
                        smoothed(row,col) = mean(block);
                    end
                end
            end
        end

        function metrics = fitGaussian2D(app,xGrid,yGrid,zData,roiMask)
            x = xGrid(roiMask);
            y = yGrid(roiMask);
            z = zData(roiMask);
            good = isfinite(x) & isfinite(y) & isfinite(z) & z > 0;
            x = x(good);
            y = y(good);
            z = z(good);
            if numel(z) < 6
                error('Not enough valid points in ROI.');
            end
            baseline0 = prctile(z,20);
            [peakValue,peakIdx] = max(z);
            amplitude0 = max(peakValue-baseline0,eps);
            xSpan = max(max(x)-min(x),eps);
            ySpan = max(max(y)-min(y),eps);
            p0 = [baseline0,amplitude0,x(peakIdx),y(peakIdx),max(xSpan/4,eps),max(ySpan/4,eps)];
            lb = [min(z),0,min(x),min(y),xSpan/100,ySpan/100];
            ub = [max(z)+2*amplitude0,3*max(z-baseline0),max(x),max(y),xSpan,ySpan];
            objective = @(p) sum((app.gaussian2D(app.boundVector(p,lb,ub),x,y)-z).^2);
            options = optimset('Display','off','MaxFunEvals',5000,'MaxIter',2000);
            pFit = fminsearch(objective,p0,options);
            pFit = app.boundVector(pFit,lb,ub);
            zFit = app.gaussian2D(pFit,x,y);
            ssRes = sum((z-zFit).^2);
            ssTot = sum((z-mean(z)).^2);
            if ssTot > 0
                fitR2 = 1 - ssRes/ssTot;
            else
                fitR2 = NaN;
            end
            metrics = struct('baseline',pFit(1),'amplitude',pFit(2), ...
                'centerX',pFit(3),'centerY',pFit(4),'sigmaX',abs(pFit(5)), ...
                'sigmaY',abs(pFit(6)),'fitR2',fitR2);
        end

        function zFit = gaussian2D(app,p,x,y)
            zFit = p(1) + p(2).*exp(-((x-p(3)).^2./(2*p(5).^2) + (y-p(4)).^2./(2*p(6).^2)));
        end

        function p = boundVector(app,p,lb,ub)
            p = max(min(p,ub),lb);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, niObj)
            app.applyScanAxisLimits();
            % 1. 将外部传进来的硬件对象赋值给本 App 的属性
            if nargin > 0
                % 1. 在这里建立 ImageAcquisitionThorlabs 实例
                % 这样 app.Acq 就不是空的了，后面的 Connect 按钮才能正常工作
                app.Acq = ImageAcquisitionThorlabs();

                app.NI = niObj;
                app.InfoTextArea.Value = '硬件对象已初始化，请连接电机。';
            else
                app.InfoTextArea.Value = '警告：未检测到输入对象。';
            end
        end

        % Button pushed function: ConnectMotorsButton
        function ConnectMotorsButtonPushed(app, event)
            % --- 逻辑 1：连接电机 (参考 thorlabmotortest.m) ---
            app.InfoTextArea.Value = '正在建立连接...';
            try
 
                % 加载 Kinesis 库
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.DeviceManagerCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.KCube.DCServoCLI.dll');

                import Thorlabs.MotionControl.DeviceManagerCLI.*
                import Thorlabs.MotionControl.GenericMotorCLI.*
                import Thorlabs.MotionControl.KCube.DCServoCLI.*

                DeviceManagerCLI.BuildDeviceList();
                serialNumbers = cell(ToArray(DeviceManagerCLI.GetDeviceList()));
                
                if length(serialNumbers) < 3
                    uialert(app.UIFigure, '检测到的序列号不足3个！', '硬件错误');
                    return;
                end

                % 这里假设顺序与 thorlabmotortest 一致：X, Z, Y
                aliases = {'X', 'Z', 'Y'};

                for i = 1:3
                    sn = serialNumbers{i};
                    dev = KCubeDCServo.CreateKCubeDCServo(sn);
                    dev.Connect(sn);
                    dev.WaitForSettingsInitialized(5000);
                    cfg = dev.LoadMotorConfiguration(sn);
                    cfg.DeviceSettingsName = 'MTS25-Z8'; 
                    cfg.UpdateCurrentConfiguration();
                    dev.SetSettings(dev.MotorDeviceSettings, true, false);
                    dev.StartPolling(250);
                    app.Acq.Motors.(aliases{i}) = dev;

                end
                
                app.InfoTextArea.Value = '电机连接成功！';
                app.Acq.setMotorSpeed('X', app.SpeedEditField.Value);
                app.Acq.setMotorSpeed('Y', app.SpeedEditField_2.Value);
                app.Acq.setMotorSpeed('Z', app.SpeedEditField_3.Value);
                app.InfoTextArea.Value = '电机连接成功并已配置速度。';
                app.updatePositions();
            catch ME
                app.InfoTextArea.Value = ['连接失败: ' ME.message];
            end
        end

        % Button pushed function: DisconnectMotorsButton
        function DisconnectMotorsButtonPushed(app, event)
            app.InfoTextArea.Value = '正在断开电机连接...';

            % 检查 Acq 对象及 Motors 属性是否存在
            if isempty(app.Acq) || isempty(app.Acq.Motors)
                app.InfoTextArea.Value = '未检测到已连接的电机。';
                return;
            end

            % 定义需要断开的轴别名
            aliases = {'X', 'Y', 'Z'};

            try
                for i = 1:length(aliases)
                    ax = aliases{i};
                    % 检查该轴是否已初始化并连接
                    if ~isempty(app.Acq.Motors.(ax)) && app.Acq.Motors.(ax).IsConnected
                        % 1. 停止轮询 (参考 thorlabmotortest.m)
                        app.Acq.Motors.(ax).StopPolling();

                        % 2. 断开连接
                        app.Acq.Motors.(ax).Disconnect();

                        % 3. 清空句柄防止误操作
                        app.Acq.Motors.(ax) = [];
                    end
                end
                app.InfoTextArea.Value = '所有设备已安全断开。';
            catch ME
                app.InfoTextArea.Value = ['断开连接时出错: ' ME.message];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % 调用我们之前写好的断开连接函数
                app.DisconnectMotorsButtonPushed();
            catch
                % 防止断开过程中报错导致窗口关不掉
            end

            % 2. 正常销毁 UI 窗口

            delete(app)

        end

        % Button pushed function: STARTSCANButton
        function STARTSCANButtonPushed(app, event)
             % --- 逻辑 2：2D Zig-Zag 扫描 (参考 ImageAcquisitionThorlabs.m) ---
             app.AbortScan = false;
             app.InfoTextArea.Value = '正在扫描...';

             % 1. 同步 UI 的参数到 Acq 对象
             app.Acq.CurrentScan.MinValues = [app.MinEditField.Value, app.MinEditField_2.Value, app.MinEditField_3.Value];
             app.Acq.CurrentScan.MaxValues = [app.MaxEditField.Value, app.MaxEditField_2.Value, app.MaxEditField_3.Value];
             app.Acq.CurrentScan.NumPoints = [app.PtsEditField.Value, app.PtsEditField_2.Value, app.PtsEditField_3.Value];
             app.Acq.CurrentScan.bEnable = [app.EnableCheckBox.Value, app.EnableCheckBox_2.Value, app.EnableCheckBox_3.Value];
             app.applyScanAxisLimits();

             app.Acq.setMotorSpeed('X', app.SpeedEditField.Value);
             app.Acq.setMotorSpeed('Y', app.SpeedEditField_2.Value);
             app.Acq.setMotorSpeed('Z', app.SpeedEditField_3.Value);

             % 2. 一键启动扫描 (传入计数器对象和 App 句柄)
             try
                 app.Acq.RunScan(app.NI, app);
                 app.InfoTextArea.Value = '扫描任务结束。';
             catch ME
                 app.InfoTextArea.Value = ['扫描出错: ' ME.message];
             end

             app.updatePositions();

        end

        % Button pushed function: SettocursorButton
        function SettocursorButtonPushed(app, event)
            % Button pushed function: SettocursorButton
            % 1. 检查电机是否连接
            if isempty(app.Acq) || isempty(app.Acq.Motors)
                uialert(app.UIFigure, '电机未连接，请先点击 Connect Motors', '操作失败');
                return;
            end

            app.InfoTextArea.Value = '正在手动移动电机...';

            try
                % 2. 从蓝色 EditField 中获取用户输入的数值
                targetX = app.EditField.Value;
                targetY = app.EditField_2.Value;
                targetZ = app.EditField_3.Value;

                % 3. 调用电机驱动执行移动
                % 注意：Thorlabs Kinesis 必须使用 System.Decimal 转换数据类型
                if ~isempty(app.Acq.Motors.X)
                    app.Acq.Motors.X.MoveTo(System.Decimal(targetX), 60000); % 60000是超时时间(ms)
                end

                if ~isempty(app.Acq.Motors.Y)
                    app.Acq.Motors.Y.MoveTo(System.Decimal(targetY), 60000);
                end

                if ~isempty(app.Acq.Motors.Z)
                    app.Acq.Motors.Z.MoveTo(System.Decimal(targetZ), 60000);
                end

                % 4. 更新状态提示
                app.InfoTextArea.Value = sprintf('移动完成！当前目标: X=%.3f, Y=%.3f, Z=%.3f', targetX, targetY, targetZ);

            catch ME
                app.InfoTextArea.Value = ['移动出错: ' ME.message];
                uialert(app.UIFigure, ME.message, '硬件错误');
            end

        end


        function CursorButtonPushed(app, event)
            app.CursorPickActive = true;
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesCursorPick, true);
            app.UIAxes.PickableParts = 'all';
            app.UIAxes.HitTest = 'on';
            try
                childObjects = app.UIAxes.Children;
                for k = 1:numel(childObjects)
                    childObjects(k).HitTest = 'off';
                end
            catch
            end
            app.InfoTextArea.Value = 'Click a point on the 2D image to set cursor.';
        end

        function UIAxesCursorPick(app, event)
            if ~app.CursorPickActive
                return;
            end
            cp = app.UIAxes.CurrentPoint;
            targetX = cp(1,1);
            targetY = cp(1,2);
            if ~isfinite(targetX) || ~isfinite(targetY)
                return;
            end
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            if targetX < min(xLimits) || targetX > max(xLimits) || targetY < min(yLimits) || targetY > max(yLimits)
                app.InfoTextArea.Value = 'Cursor point is outside the scan axes.';
                return;
            end
            app.EditField.Value = targetX;
            app.EditField_2.Value = targetY;
            try
                if ~isempty(app.CursorMarker) && isvalid(app.CursorMarker)
                    delete(app.CursorMarker);
                end
            catch
            end
            hold(app.UIAxes,'on');
            app.CursorMarker = plot(app.UIAxes,targetX,targetY,'wx','MarkerSize',12,'LineWidth',2);
            hold(app.UIAxes,'off');
            app.CursorPickActive = false;
            app.InfoTextArea.Value = sprintf('Cursor set: X=%.4f, Y=%.4f, Z=%.4f. Click Set to cursor to move motors.', targetX, targetY, app.EditField_3.Value);
        end

        function ROIButtonPushed(app, event)
            imageObject = app.getCurrentImage();
            if isempty(imageObject)
                app.InfoTextArea.Value = 'No 2D image found. Run a scan before selecting ROI.';
                return;
            end
            try
                if ~isempty(app.GaussianRoi) && isvalid(app.GaussianRoi)
                    delete(app.GaussianRoi);
                end
            catch
            end
            try
                app.GaussianRoi = drawrectangle(app.UIAxes,'Color','w');
                app.InfoTextArea.Value = 'ROI selected. Click Gaussian fit to fit the smoothed ROI data.';
            catch ME
                app.InfoTextArea.Value = ['ROI selection failed: ' ME.message];
            end
        end

        function GaussianfitButtonPushed(app, event)
            imageObject = app.getCurrentImage();
            if isempty(imageObject)
                app.InfoTextArea.Value = 'No 2D image found for Gaussian fit.';
                return;
            end
            if isempty(app.GaussianRoi) || ~isvalid(app.GaussianRoi)
                app.InfoTextArea.Value = 'Select an ROI first.';
                return;
            end
            cData = double(imageObject.CData);
            [xGrid,yGrid] = app.getImageGrid(imageObject,size(cData));
            roiPosition = app.GaussianRoi.Position;
            roiMask = xGrid >= roiPosition(1) & xGrid <= roiPosition(1)+roiPosition(3) & ...
                yGrid >= roiPosition(2) & yGrid <= roiPosition(2)+roiPosition(4);
            roiMask = roiMask & isfinite(cData) & cData > 0;
            if nnz(roiMask) < 6
                app.InfoTextArea.Value = 'ROI has too few valid image points for Gaussian fit.';
                return;
            end
            try
                smoothed = app.smooth2D(cData,3);
                metrics = app.fitGaussian2D(xGrid,yGrid,smoothed,roiMask);
            catch ME
                app.InfoTextArea.Value = ['Gaussian fit failed: ' ME.message];
                return;
            end
            try
                if ~isempty(app.GaussianCenterMarker) && isvalid(app.GaussianCenterMarker)
                    delete(app.GaussianCenterMarker);
                end
            catch
            end
            hold(app.UIAxes,'on');
            app.GaussianCenterMarker = plot(app.UIAxes,metrics.centerX,metrics.centerY,'m+','MarkerSize',14,'LineWidth',2.5);
            hold(app.UIAxes,'off');
            app.InfoTextArea.Value = sprintf(['Gaussian fit center: X=%.4f, Y=%.4f. ' ...
                'Amplitude=%.4g, sigmaX=%.4f, sigmaY=%.4f, R2=%.3f. ' ...
                'X/Y fields were not changed.'], ...
                metrics.centerX,metrics.centerY,metrics.amplitude,metrics.sigmaX,metrics.sigmaY,metrics.fitR2);
        end

        % Button pushed function: STOPSCANButton
        function STOPSCANButtonPushed(app, event)
            % 修改标志位
            app.AbortScan = true;

            % 在界面提示用户
            app.InfoTextArea.Value = '正在请求停止，请稍候...';
            % app.STOPSCANButton.BackgroundColor = [1, 0, 0]; % 变红表示已触发
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMotorsButtonPushed, true);
            app.ConnectMotorsButton.Text = 'Connect Motors';
            app.STARTSCANButton = uibutton(app.UIFigure, 'push');
            app.STARTSCANButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSCANButtonPushed, true);
            app.STARTSCANButton.Text = 'START SCAN';
            app.STOPSCANButton = uibutton(app.UIFigure, 'push');
            app.STOPSCANButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSCANButtonPushed, true);
            app.STOPSCANButton.Text = 'STOP SCAN';
            app.SettocursorButton = uibutton(app.UIFigure, 'push');
            app.SettocursorButton.ButtonPushedFcn = createCallbackFcn(app, @SettocursorButtonPushed, true);
            app.SettocursorButton.Text = 'Set to cursor';
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X Axis';
            app.MinEditFieldLabel = uilabel(app.XAxisPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MinEditField.Limits = [0 24];
            app.MinEditField.Value = 9;
            app.MaxEditFieldLabel = uilabel(app.XAxisPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MaxEditField.Limits = [10 24];
            app.MaxEditField.Value = 11;
            app.PtsEditFieldLabel = uilabel(app.XAxisPanel);
            app.PtsEditFieldLabel.Text = 'Pts';
            app.PtsEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PtsEditField.Value = 10;
            app.EditField = uieditfield(app.XAxisPanel, 'numeric');
            app.EditField.Value = 10;
            app.EnableCheckBox = uicheckbox(app.XAxisPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox.Value = true;
            app.SpeedEditFieldLabel = uilabel(app.XAxisPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.SpeedEditField.Value = 1;
            app.YAxisPanel = uipanel(app.UIFigure);
            app.YAxisPanel.Title = 'Y Axis';
            app.MinEditField_2Label = uilabel(app.YAxisPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MinEditField_2.Limits = [0 24];
            app.MinEditField_2.Value = 9;
            app.MaxEditField_2Label = uilabel(app.YAxisPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MaxEditField_2.Limits = [0 24];
            app.MaxEditField_2.Value = 11;
            app.PtsEditField_2Label = uilabel(app.YAxisPanel);
            app.PtsEditField_2Label.Text = 'Pts';
            app.PtsEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.PtsEditField_2.Value = 10;
            app.EditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.EditField_2.Value = 10;
            app.EnableCheckBox_2 = uicheckbox(app.YAxisPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.EnableCheckBox_2.Value = true;
            app.SpeedEditField_2Label = uilabel(app.YAxisPanel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.SpeedEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.SpeedEditField_2.Value = 1;
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z Axis';
            app.MinEditField_3Label = uilabel(app.ZAxisPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MinEditField_3.Limits = [0 20];
            app.MaxEditField_3Label = uilabel(app.ZAxisPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MaxEditField_3.Limits = [0 24];
            app.MaxEditField_3.Value = 10;
            app.PtsEditField_3Label = uilabel(app.ZAxisPanel);
            app.PtsEditField_3Label.Text = 'Pts';
            app.PtsEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.PtsEditField_3.Value = 10;
            app.EditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.EnableCheckBox_3 = uicheckbox(app.ZAxisPanel);
            app.EnableCheckBox_3.Text = 'Enable';
            app.SpeedEditField_3Label = uilabel(app.ZAxisPanel);
            app.SpeedEditField_3Label.Text = 'Speed';
            app.SpeedEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.SpeedEditField_3.Value = 2;
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.DisconnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectMotorsButtonPushed, true);
            app.DisconnectMotorsButton.Text = 'Disconnect Motors';
            app.CursorButton = uibutton(app.UIFigure, 'push');
            app.CursorButton.ButtonPushedFcn = createCallbackFcn(app, @CursorButtonPushed, true);
            app.CursorButton.Text = 'Cursor';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.GaussianfitButton = uibutton(app.UIFigure, 'push');
            app.GaussianfitButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianfitButtonPushed, true);
            app.GaussianfitButton.Text = 'Gaussian fit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magnetalignment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end